classdef mainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NmerodepalavrascorretasLabel  matlab.ui.control.Label
        palavrasCorretas              matlab.ui.control.NumericEditField
        ComputarButton                matlab.ui.control.Button
        ExecutarButton                matlab.ui.control.Button
        ProximoButton                 matlab.ui.control.Button
        StatusBox                     matlab.ui.container.Panel
        udioemexecuoTextAreaLabel     matlab.ui.control.Label
        fraseExecucao                 matlab.ui.control.TextArea
        StatusdereproduoLampLabel     matlab.ui.control.Label
        StatusdereproduoLamp          matlab.ui.control.Lamp
        VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel  matlab.ui.control.Label
        Canaisativos                  matlab.ui.container.ButtonGroup
        Esquerda                      matlab.ui.control.RadioButton
        Direita                       matlab.ui.control.RadioButton
        EsquerdaDireita               matlab.ui.control.RadioButton
        Saida                         matlab.ui.container.ButtonGroup
        IC                            matlab.ui.control.RadioButton
        Normoouvintes                 matlab.ui.control.RadioButton
        nomePaciente                  matlab.ui.control.Label
        p1Button_2                    matlab.ui.control.StateButton
        p1Button_3                    matlab.ui.control.StateButton
        p1Button_4                    matlab.ui.control.StateButton
        p1Button_5                    matlab.ui.control.StateButton
        p1Button_6                    matlab.ui.control.StateButton
        p1Button_7                    matlab.ui.control.StateButton
        p1Button_8                    matlab.ui.control.StateButton
        p1Button_9                    matlab.ui.control.StateButton
        p1Button_1                    matlab.ui.control.StateButton
        Pannel1                       matlab.ui.container.Panel
        AdicionarlistaButton          matlab.ui.control.Button
        listBoxOrdemExec              matlab.ui.control.ListBox
        nomeLista                     matlab.ui.control.Label
        EncerrarButton                matlab.ui.control.Button
        NmerototaldepalavrasEditFieldLabel  matlab.ui.control.Label
        totalPalavras                 matlab.ui.control.NumericEditField
        PushToTalkButton              matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        FolderFullPath % Description
        Resultados % Description
        NumTotalPalavras % Description
        acertoPercentual % Description
        acertoPercentualUp  % Description
        acertoPercentualDw  % Description
        snrVec % Description
        PalavrasFrase % Description
        palCorretas % Description
        palTodas % Description
        flagUserMap % Description
        rootFolder % Description
        numberAudios % Description
    end
    
    properties (Access = private)
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.rootFolder = pwd;
            addpath(genpath(pwd))
            app.UIFigure.Resize = 'off';
            for iWord=1:9
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
            end
            app.palCorretas = cell(26,9);
            app.palTodas = cell(26,9);
        end

        % Button pushed function: AdicionarlistaButton
        function AdicionarlistaButtonPushed(app, event)
            % Seleciona pasta via UI e lê arquivos .WAV
            folderPath = uigetdir('','Selecione pasta com arquivos .wav da lista de sentenças.');
            cd(folderPath);
            addpath(genpath(folderPath));
            app.FolderFullPath = folderPath;
            listaAudios = dir('*.wav');
            cd(app.rootFolder);
            
            
            % Trick para não minimizar app quando rodar uigetdir()
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Ordenar audios:
            % Arquivos fala femininas  (F), SNR-8 to SNR12
            % Arquivos fala masculinas (M), SNR12 to SNR-8
            
            % Atualiza listBox com arquivos WAV da pasta selecionada
            app.listBoxOrdemExec.Items = {listaAudios.name};
            app.listBoxOrdemExec.ItemsData = 1:length({listaAudios.name});
            % Seleciona primeiro audio para execução
            app.listBoxOrdemExec.Value = 1;
            % Lê nome da pasta e plccxota na interface gráfica para controle
            [filepath,FolderName,ext] = fileparts(folderPath);
            app.nomeLista.Text = FolderName;
            
            % Cria vetor de resultados (adaptar para aplicação mais útil)
            app.Resultados = zeros(length({listaAudios.name}),1);
            app.NumTotalPalavras = zeros(length({listaAudios.name}),1);
            
            % Atualiza permissoes botoes
            app.ExecutarButton.Enable = 'on';
            
            
        end

        % Button pushed function: ComputarButton
        function ComputarButtonPushed(app, event)
            % Habilita troca para próximo audio
            if app.listBoxOrdemExec.Value + 1 > size(app.listBoxOrdemExec.Items)
                app.ProximoButton.Enable = 'off';
            else
                app.ProximoButton.Enable = 'on';
            end
            cumCorr = 0;
            audioExec = app.listBoxOrdemExec.Value;
            % palCorretasFrase = cell(length(app.PalavrasFrase),1);
            for iWord=1:length(app.PalavrasFrase)
                eval(sprintf('value = app.p1Button_%.0f.Value;',iWord))
                eval(sprintf('palavra = app.p1Button_%.0f.Text;',iWord))
                if value == 1
                    cumCorr = cumCorr + 1;
                    app.palCorretas{audioExec,iWord} = palavra;
                    
                end
                
            end
            %app.palCorretas(audioExec) = palCorretasFrase;
            app.palavrasCorretas.Value = cumCorr;
            app.Resultados(audioExec) = app.palavrasCorretas.Value;
            app.NumTotalPalavras(audioExec) = app.totalPalavras.Value;
            fprintf('Palavras corretas para audio %s = %.0f \n',app.listBoxOrdemExec.Items{app.listBoxOrdemExec.Value},app.palavrasCorretas.Value)
            %app.numberAudios = length(app.listBoxOrdemExec.Items);
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Carrega dados do arquivo
            fileName = app.listBoxOrdemExec.Items(app.listBoxOrdemExec.Value);
            fullFileName = fullfile(app.FolderFullPath,fileName);
            
            % Le audio e metadata
            [signal, fs] = audioread(fullFileName{1});
            audioInformations = audioinfo(fullFileName{1});
            
            % Implementar metadata (fala completa) na seção 'Comment' do arquivo wav
            % Para isto: audiowrite(filename,y,fs,'Comment','frase por extenso')
            % app.fraseExecucao.Value = fileName;
            % app.fraseExecucao.Value = audioInformations.Title;
            app.fraseExecucao.Value = audioInformations.Title;
            
            % Numero de palavras na frase
            app.PalavrasFrase = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.PalavrasFrase);
            
            for iWord=1:length(app.PalavrasFrase)
                %strOn = 'on';
                app.palTodas{app.listBoxOrdemExec.Value,iWord} = app.PalavrasFrase(iWord);
                eval(sprintf('app.p1Button_%.0f.Text=app.PalavrasFrase{%.0f};',iWord,iWord))
                eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end
            
            % Adicionar a app.relacaoPalavrasCorretas as palavras da frase, em cada Button
            % app.relacaoPalavrasCorretas.


           % Atualiza permissões e luz de status
            app.StatusdereproduoLamp.Color = 'green';
            
            if app.Normoouvintes.Value == 1
                % Reproducao do sinal - A ser adaptada para comunicar com CCi-Mobile
                player = audioplayer(signal,fs);
                player.play
                pause(length(signal)/fs) % intervalo para reprodução
                
            elseif app.IC.Value == 1 
                
                % Call function 'sendStreamer(signal)' - ONLY WHEN HAVE INTERFACE
                [patientName] = sendStreamer(fullFileName{1}, app.flagUserMap);
                
                % Return flag to 0, until finish list
                if app.flagUserMap == 1
                    app.nomePaciente.Text = ['Paciente: ', patientName];
                    app.flagUserMap = 0;
                end
                
                % Trick para não minimizar app quando rodar uigetdir()
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                disp('SENT TO IC!')
                
            end


            app.StatusdereproduoLamp.Color = 'red';
            app.ComputarButton.Enable = 'on';
        end

        % Button pushed function: ProximoButton
        function ProximoButtonPushed(app, event)
            % Atualiza contador de lista
            
            
            app.listBoxOrdemExec.Value = app.listBoxOrdemExec.Value + 1;
            app.palavrasCorretas.Value = 0;
            app.fraseExecucao.Value = '';
            % Desabilita botão 'proximo audio' até reprodução do audio atual
            for iWord=1:9
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
                eval(sprintf('app.p1Button_%.0f.Value = 0;',iWord))
            end
            app.ProximoButton.Enable = 'off';
            app.ComputarButton.Enable = 'off';
            
            
        end

        % Button pushed function: EncerrarButton
        function EncerrarButtonPushed(app, event)
            
            %app.numberAudios = length(app.listBoxOrdemExec.Items);
            %app.numberAudios = app.listBoxOrdemExec.Value;
            app.numberAudios = length(app.NumTotalPalavras);
            
            % Set flag related to user map configuration to 0. 
            % So, in next list, has to be defined a new map user
            app.flagUserMap = 1; % Set flag variable to 1
            
            % Relação de acertos percentuais
            app.acertoPercentual = app.Resultados./app.NumTotalPalavras;
            
            resultados.numTotalPalavras = app.NumTotalPalavras;
            resultados.numAcertos = app.Resultados;
            resultados.palCorretas = app.palCorretas;
            resultados.palTodas = app.palTodas;
            
            [savefilename,savepathname] = uiputfile('*.mat');
            save(fullfile(savepathname,savefilename),'resultados')
            %app.delete
            
            
            %%% Plot psychometric curve
            % Split into up and down results
            SNR = [-8 -6 -4 -2 0 2 4 6 8 10 12 14 16 30];
            acertosUp = resultados.numAcertos(1:app.numberAudios/2)./resultados.numTotalPalavras(1:app.numberAudios/2);
            acertosDw = resultados.numAcertos(app.numberAudios/2+1:end)./resultados.numTotalPalavras(app.numberAudios/2+1:end);
            acertosDw = fliplr(acertosDw');
            acertosMn = (acertosDw'+acertosUp)./2;
            
            %% ----- Fit method 1
            % Call function 'FitPsycheCurveWH.m'
            % This parameters are redefine in function of intelligibilit curve
            %             SPs = [0.35, 0.1, 10, 10; % Upper limits for g, l, u ,v
            %                 0.01, 0.05, 5, 1; % Start points for g, l, u ,v
            %                 0, 0, -5, 0]; % Lower limits for  g, l, u ,v
            %             
            %             [coeffs, curve] = FitPsycheCurveWH(SNR, acertosMn, SPs); % Try to fit without fitting toolbox
            %% ----- 
            
            %% ----- Fit method 2
            targets = [0.25, 0.5, 0.75]; % 25%, 50% and 75% performance
            weights = ones(1,length(SNR)); % No weighting
            
            [~, curve, ~] = ...
                FitPsycheCurveLogit(SNR', acertosMn, weights, targets);
            %% ----- 
            
            h(1) = plot(SNR, 100.*acertosMn, '*r');
            hold on
            h(2) = plot(curve(:,1),100.*curve(:,2));
            xlabel('SNR')
            ylabel('Acertos(%)')
            
            % Intert indication of SRT50 and plot curves..
            SRTpos = find(round(100*curve(:,2))==50);
            SRT50 = curve(SRTpos(1,1),1);
            
            % Draw line
            xline = [50.*ones(SRTpos(1,1),1)];% zeros(size(curve,1)-SRTpos(1,1),1)];
            h(3)=plot(curve(1:SRTpos,1),xline,'--k');
            h(5) = plot([SRT50 SRT50],[0 50],'--k');
            
            % Insert legend
            h(4) = plot(SRT50,50,'kx','DisplayName',['SRT50 = ' num2str(SRT50)]);
            legend(h(4),'location','southeast')
            
            app.delete
        end

        % Value changed function: PushToTalkButton
        function PushToTalkButtonValueChanged(app, event)
            value = app.PushToTalkButton.Value;
            
            if value == 1 % BTE's ON
                
                tDur = 5000;
                humanTest = 0;
                eletrodogramPlot = 0;
                app.StatusdereproduoLamp.Color = 'Blue';
                patientName = pushToTalk( tDur,humanTest,eletrodogramPlot,app.flagUserMap );
                
                % Return flag to 0, until finish list
                if app.flagUserMap == 1
                    app.flagUserMap = 0;
                    app.nomePaciente.Text = ['Paciente :', patientName]; 
                end
                
                app.StatusdereproduoLamp.Color = 'red';
                % Trick para não minimizar app quando rodar uigetdir()
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                waitfor(tDur)
                app.PushToTalkButton.Value = 0;
                
            else          % BTE's OFF
                
            end
        end

        % Selection changed function: Saida
        function SaidaSelectionChanged(app, event)
            selectedButton = app.Saida.SelectedObject;
            if app.IC.Value == 1
                
                fprintf('Change to IC mode \n');
                % First time that user press, request to load map and set flagUserMap == 1 until end of list
                app.flagUserMap = 1; % Set flag variable to 1
                
            else
                
                fprintf('Change to Normo-ouvinte mode \n');
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.NmerodepalavrascorretasLabel = uilabel(app.UIFigure);
            app.NmerodepalavrascorretasLabel.Text = 'Número de palavras corretas';
            app.palavrasCorretas = uieditfield(app.UIFigure, 'numeric');
            app.palavrasCorretas.Editable = 'off';
            app.ComputarButton = uibutton(app.UIFigure, 'push');
            app.ComputarButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarButtonPushed, true);
            app.ComputarButton.Enable = 'off';
            app.ComputarButton.Text = 'Computar';
            app.ExecutarButton = uibutton(app.UIFigure, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ProximoButton = uibutton(app.UIFigure, 'push');
            app.ProximoButton.ButtonPushedFcn = createCallbackFcn(app, @ProximoButtonPushed, true);
            app.ProximoButton.Enable = 'off';
            app.ProximoButton.Text = 'Próximo';
            app.StatusBox = uipanel(app.UIFigure);
            app.StatusBox.Title = 'Gerenciamento do ensaio';
            app.udioemexecuoTextAreaLabel = uilabel(app.StatusBox);
            app.udioemexecuoTextAreaLabel.Text = 'Áudio em execução';
            app.fraseExecucao = uitextarea(app.StatusBox);
            app.StatusdereproduoLampLabel = uilabel(app.StatusBox);
            app.StatusdereproduoLampLabel.Text = 'Status de reprodução: ';
            app.StatusdereproduoLamp = uilamp(app.StatusBox);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel = uilabel(app.StatusBox);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel.Text = {'Verde  - Áudio executando '; 'Vermelho   -  Ouvinte pronunciando'; 'Azul - Comunicação em andamento'};
            app.Canaisativos = uibuttongroup(app.StatusBox);
            app.Canaisativos.Title = 'Canais ativos (IC)';
            app.Esquerda = uiradiobutton(app.Canaisativos);
            app.Esquerda.Text = 'Esquerda';
            app.Esquerda.Value = true;
            app.Direita = uiradiobutton(app.Canaisativos);
            app.Direita.Text = 'Direita';
            app.EsquerdaDireita = uiradiobutton(app.Canaisativos);
            app.EsquerdaDireita.Text = 'Esquerda / Direita';
            app.Saida = uibuttongroup(app.StatusBox);
            app.Saida.SelectionChangedFcn = createCallbackFcn(app, @SaidaSelectionChanged, true);
            app.Saida.Title = 'Saida';
            app.IC = uiradiobutton(app.Saida);
            app.IC.Text = 'IC';
            app.Normoouvintes = uiradiobutton(app.Saida);
            app.Normoouvintes.Text = 'Normo-ouvintes';
            app.Normoouvintes.Value = true;
            app.nomePaciente = uilabel(app.Saida);
            app.nomePaciente.Text = '';
            app.p1Button_2 = uibutton(app.StatusBox, 'state');
            app.p1Button_2.Text = 'p1';
            app.p1Button_3 = uibutton(app.StatusBox, 'state');
            app.p1Button_3.Text = 'p1';
            app.p1Button_4 = uibutton(app.StatusBox, 'state');
            app.p1Button_4.Text = 'p1';
            app.p1Button_5 = uibutton(app.StatusBox, 'state');
            app.p1Button_5.Text = 'p1';
            app.p1Button_6 = uibutton(app.StatusBox, 'state');
            app.p1Button_6.Text = 'p1';
            app.p1Button_7 = uibutton(app.StatusBox, 'state');
            app.p1Button_7.Text = 'p1';
            app.p1Button_8 = uibutton(app.StatusBox, 'state');
            app.p1Button_8.Text = 'p1';
            app.p1Button_9 = uibutton(app.StatusBox, 'state');
            app.p1Button_9.Text = 'p1';
            app.p1Button_1 = uibutton(app.StatusBox, 'state');
            app.p1Button_1.Text = 'p1';
            app.Pannel1 = uipanel(app.UIFigure);
            app.Pannel1.Title = 'Ordem de execução dos áudios';
            app.AdicionarlistaButton = uibutton(app.Pannel1, 'push');
            app.AdicionarlistaButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarlistaButtonPushed, true);
            app.AdicionarlistaButton.Text = 'Adicionar lista';
            app.listBoxOrdemExec = uilistbox(app.Pannel1);
            app.listBoxOrdemExec.Items = {};
            app.listBoxOrdemExec.Enable = 'off';
            app.listBoxOrdemExec.Value = {};
            app.nomeLista = uilabel(app.Pannel1);
            app.nomeLista.Text = '';
            app.EncerrarButton = uibutton(app.UIFigure, 'push');
            app.EncerrarButton.ButtonPushedFcn = createCallbackFcn(app, @EncerrarButtonPushed, true);
            app.EncerrarButton.Text = 'Encerrar';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.UIFigure);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Número total de palavras';
            app.totalPalavras = uieditfield(app.UIFigure, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.PushToTalkButton = uibutton(app.UIFigure, 'state');
            app.PushToTalkButton.ValueChangedFcn = createCallbackFcn(app, @PushToTalkButtonValueChanged, true);
            app.PushToTalkButton.Text = 'Push To Talk';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainInterface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end