classdef interface_nomohe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlPanel               matlab.ui.container.Panel
        StartButton                matlab.ui.control.StateButton
        GainButtonGroup            matlab.ui.container.ButtonGroup
        MMSEButton                 matlab.ui.control.RadioButton
        WienerButton               matlab.ui.control.RadioButton
        BinaryMaskButton           matlab.ui.control.RadioButton
        UnprocessedButton          matlab.ui.control.RadioButton
        noisetypeButtonGroup       matlab.ui.container.ButtonGroup
        babble                     matlab.ui.control.RadioButton
        icra                       matlab.ui.control.RadioButton
        SSN_IEEE                   matlab.ui.control.RadioButton
        noiseazimuthangles         matlab.ui.container.ButtonGroup
        plusninety                 matlab.ui.control.RadioButton
        zero                       matlab.ui.control.RadioButton
        minusninety                matlab.ui.control.RadioButton
        sideofCIButtonGroup        matlab.ui.container.ButtonGroup
        leftButton                 matlab.ui.control.RadioButton
        rightButton                matlab.ui.control.RadioButton
        bilateralButton            matlab.ui.control.RadioButton
        DatarepresentationPanel    matlab.ui.container.Panel
        udioemexecuoTextAreaLabel  matlab.ui.control.Label
        fraseExecucao              matlab.ui.control.TextArea
        StatusdereproduoLampLabel  matlab.ui.control.Label
        StatusdereproduoLamp       matlab.ui.control.Lamp
        VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel  matlab.ui.control.Label
        p1Button_1                 matlab.ui.control.StateButton
        p1Button_2                 matlab.ui.control.StateButton
        p1Button_3                 matlab.ui.control.StateButton
        p1Button_4                 matlab.ui.control.StateButton
        p1Button_5                 matlab.ui.control.StateButton
        p1Button_6                 matlab.ui.control.StateButton
        p1Button_7                 matlab.ui.control.StateButton
        p1Button_8                 matlab.ui.control.StateButton
        p1Button_9                 matlab.ui.control.StateButton
        Panel                      matlab.ui.container.Panel
        TotalwordscorrectLabel     matlab.ui.control.Label
        palavrasCorretas           matlab.ui.control.NumericEditField
        NmerototaldepalavrasEditFieldLabel  matlab.ui.control.Label
        totalPalavras              matlab.ui.control.NumericEditField
        Next                       matlab.ui.control.StateButton
        ComputeButton              matlab.ui.control.Button
        p1Button_10                matlab.ui.control.StateButton
        p1Button_11                matlab.ui.control.StateButton
        repeatLastAudio            matlab.ui.control.StateButton
        SNRdBButtonGroup           matlab.ui.container.ButtonGroup
        minusfivedb                matlab.ui.control.RadioButton
        zerodb                     matlab.ui.control.RadioButton
        plusfivadb                 matlab.ui.control.RadioButton
        twofivedb                  matlab.ui.control.RadioButton
        sevenfivedb                matlab.ui.control.RadioButton
        NumberAudio                matlab.ui.control.Label
    end


    properties (Access = public)
        signal_out              % Description
        flagSNRStep             % Description
        flagCounterCicles       % Description
        snr_value               % Description 
        earref                  % Description 
        AZN                     % Description 
        correctRate             % Description 
        sentenceWords           % Description 
        FolderFullPath          % Description 
        fileName                % Description  
        correctWords            % Description  
        Results                 % Description  
        NumTotalWords           % Description 
        audioExec               % Description 
        wordsAll                % Description 
        snr_vecValues           % Description 
        MF                      % Description 
        folder1                 % Description 
        audioN                  % Description 
        previousAudio           % Description 
        flagTwodown             % Description 
        flagChooseOtherGain     % Description
        channelOutput           % Description
        flagVSameAudio          % Description
        flagAudioRepeated       % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.UIFigure.Resize = 'off';
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
            end
            app.correctWords = cell(200,11);
            app.wordsAll = cell(200,11);
            app.previousAudio = cell(200,1); % Used to control which audio has been show
            app.flagChooseOtherGain = 0; % control flag of first algorithm choose
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
            % Flags two down-one up
            app.flagTwodown = 1;
            
            % Create vector of results (considering that, during each list, will be presented 200 sentences)
            app.Results = zeros(200,1);
            app.NumTotalWords = zeros(200,1);
            app.snr_vecValues = zeros(200,1);
            
            if app.flagChooseOtherGain == 0
                % This index has to be related to randomly list            
                app.audioExec = 1;
            end            
            
            % Counter of cicles (6)
            app.flagCounterCicles = 0;
            
            % Control SNR step (0 - 4dB / 1 - 2dB). Update based on app.correctRate variable
            app.flagSNRStep = 0;   
            
            % Initial SNR presented
            if app.minusfivedb.Value == 1
                app.snr_value = -5;
            elseif app.zerodb.Value == 1
                app.snr_value = 0;
            elseif app.plusfivadb.Value == 1
                app.snr_value = 5;
            elseif app.twofivedb.Value == 1
                app.snr_value = 2.5;
            elseif app.sevenfivedb.Value == 1
                app.snr_value = 7.5;
            end
            
            % Get CI side (1 - right / 4 - left)
            if app.leftButton.Value == 1
                app.earref = 1;
                app.channelOutput = 1; % Output channel (related to IC side)
            elseif app.rightButton.Value == 1
                app.earref = 4;
                app.channelOutput = 2; % Output channel (related to IC side)
            else
                app.earref = 1; %(Bilateral not considered, so left is the reference)
            end
            
            % Get noise signal azimuth
            if app.plusninety.Value == 1
                app.AZN = 90;
            elseif app.zero.Value == 1
                app.AZN = 0;
            else
                app.AZN = -90;
            end            
            
            % Get audio from .\Data\Alcaim1_ aleatory ----------------------------
            % compose name aleatory
            % 1. choose between M and F
            MFtemp = randi([0 1],1,1);
            if MFtemp == 0
                app.MF = 'M';
            else
                app.MF = 'F';
            end
            % 2. choose which list
            NL = randi([1 10],1,1);
            if NL < 10
                app.folder1 = strcat(app.MF,'000',num2str(NL));
            else
                app.folder1 = strcat(app.MF,'00',num2str(NL));
            end
            % 3. choose which audio
            NA = randi([1 10],1,1);
            if NA < 10
                app.audioN = strcat(app.folder1,'00',num2str(NA));
            else
                app.audioN = strcat(app.folder1,'0',num2str(NA));
            end
            
            app.FolderFullPath = strcat('.\Data\Alcaim1_\',app.folder1,'\');
            app.fileName = app.audioN;
            %             app.FolderFullPath = strcat('.\Data\Alcaim1_\M0001\');
            %             app.fileName = 'M0001009';
            
            app.previousAudio{app.audioExec,1} = app.audioN;
            %%%% ----------------------------
            
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            
            % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;
            
            % Numero de palavras na frase
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                 eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end            
            
            % Call function sigproc - Process audio
            app.signal_out = sigproc(char(app.FolderFullPath), char(app.fileName), ...
                app.noisetypeButtonGroup.SelectedObject.Text, app.snr_value, ...
                app.GainButtonGroup.SelectedObject.Text, app.earref, app.AZN, 0);              
            
            
            % Call vocoder function
            [app.signal_out, ~] = CI_Vocoder_Cochlear(app.signal_out(:,app.channelOutput),...
                16000, 'CI');
                
            % update status
            app.StatusdereproduoLamp.Color = 'green';
                    
            % reproduce audio signal 
            % About the test: record mono sterio audio and two source audio.
            %if app.Stereo.Value == 1
            %    player = audioplayer(app.signal_out,16000);
            %elseif app.Mono.Value == 1
            %    player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            %end
            %player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            player = audioplayer(app.signal_out,16000);
            player.play
            pause(length(app.signal_out)/8000) % intervalo para reprodução
                
            app.StatusdereproduoLamp.Color = 'red';
            app.ComputeButton.Enable = 'on';
            app.repeatLastAudio.Enable = 'on';
            
        end

        % Value changed function: repeatLastAudio
        function repeatLastAudioValueChanged(app, event)
            value = app.repeatLastAudio.Value;
            
            % update status
            app.StatusdereproduoLamp.Color = 'green';            
            
            % reproduce audio signal (output has to be based on CI channel?)
            %             if app.Stereo.Value == 1
            %                 player = audioplayer(app.signal_out,16000);
            %             elseif app.Mono.Value == 1
            %                 player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            %             end
                        
            player = audioplayer(app.signal_out,16000);
            player.play
            pause(length(app.signal_out)/8000) % intervalo para reprodução            
           
            app.StatusdereproduoLamp.Color = 'red';
            app.ComputeButton.Enable = 'on';              
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            
            % Indicate numbers of audios executed (max = 200)
            app.NumberAudio.Text = num2str(app.audioExec);
            
            cumCorr = 0;
            
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('value = app.p1Button_%.0f.Value;',iWord))
                eval(sprintf('palavra = app.p1Button_%.0f.Text;',iWord))
                if value == 1
            
                    cumCorr = cumCorr + 1;
                    app.correctWords{app.audioExec,iWord} = palavra;
                    
                end
                
            end
            
            app.palavrasCorretas.Value = cumCorr;
            app.Results(app.audioExec) = app.palavrasCorretas.Value;
            app.NumTotalWords(app.audioExec) = app.totalPalavras.Value;            
            app.snr_vecValues(app.audioExec) = app.snr_value;
            
            app.Next.Enable = 'on';
            
            % Now compute rate of correct words, then update variables app.correctRate and app.snr_value                            
            app.correctRate = app.palavrasCorretas.Value/app.totalPalavras.Value;
            
            % Counter of cicles (6)
            app.flagCounterCicles = app.flagCounterCicles + 1;
            
            % Counter of completed cicles (10 sentences, per algorithm, per SNR)
            if app.flagCounterCicles >= 6
                                   
                % save all variables
                disp('Save variables!!')
                
                resultados.numTotalPalavras = app.NumTotalWords;
                resultados.numAcertos = app.Results;
                resultados.palCorretas = app.correctWords;
                resultados.wordsAll = app.wordsAll;
                resultados.snr_vecValues = app.snr_vecValues;
                
                
                [savefilename,savepathname] = uiputfile('*.mat');
                save(fullfile(savepathname,savefilename),'resultados')                
                
                app.ComputeButton.Enable = 'off';
                app.Next.Enable = 'off';
                app.StartButton.Enable = 'on';
                
                app.flagChooseOtherGain = 1;
                msgbox('Choose other Gain (algorithm!) and SNR!')
                
                %app.Next.Enable = 'off';
                %app.StartButton.Enable = 'on';
                
                %%% Create a clever way to close/stop program..
                % Stop this list!
                %app.delete
                
            end                     
            
            
        end

        % Value changed function: Next
        function NextValueChanged(app, event)
            value = app.Next.Value;
            
            % Increment list of execution audios
            app.audioExec = app.audioExec + 1;             
            
            % Disable buttons
            app.ComputeButton.Enable = 'off';
            app.Next.Enable = 'off';
            
            %             % Update SNR presented is 30dB
            %             app.valueSNR.Text = num2str(app.snr_value);
            %             app.numberCicle.Text = num2str(app.flagCounterCicles);
            %             app.sentence.Text = app.fileName;
            
            % Update sentence words presentation
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
                eval(sprintf('app.p1Button_%.0f.Value = 0;',iWord))
            end            
            
            %%%% !!!!!!!!!!!!!!!!!!!!!!!!!!!
            %%% AFTER FINISH ALGORITHM 1, THE NEXT SET OF AUDIOS HAS TO BE DIFFERENTS!
            app.flagVSameAudio = 0;
            app.flagAudioRepeated = 0;
            while app.flagAudioRepeated == 0
                % Get audio from .\Data\Alcaim1_ aleatory ----------------------------
                % compose name aleatory
                % 1. choose between M and F
                MFtemp = randi([0 1],1,1);
                if MFtemp == 0
                    app.MF = 'M';
                else
                    app.MF = 'F';
                end
                % 2. choose which list
                NL = randi([1 10],1,1);
                if NL < 10
                    app.folder1 = strcat(app.MF,'000',num2str(NL));
                else
                    app.folder1 = strcat(app.MF,'00',num2str(NL));
                end
                % 3. choose which audio
                NA = randi([1 10],1,1);
                if NA < 10
                    app.audioN = strcat(app.folder1,'00',num2str(NA));
                else
                    app.audioN = strcat(app.folder1,'0',num2str(NA));
                end
            
                app.FolderFullPath = strcat('.\Data\Alcaim1_\',app.folder1,'\');
                app.fileName = app.audioN;
                
                app.previousAudio{app.audioExec,1} = app.audioN;
                
                % Chech if audio has already been show (CHECK ON ALL CELL...)              
                for k=1:1:app.audioExec-1
                    if strcmp(app.audioN,app.previousAudio{k,1}) == 1
                        
                        MFtemp = randi([0 1],1,1);
                        if MFtemp == 0
                            app.MF = 'F';
                        else
                            app.MF = 'M';
                        end
                        % 2. choose which list
                        NL = randi([1 10],1,1);
                        if NL < 10
                            app.folder1 = strcat(app.MF,'000',num2str(NL));
                        else
                            app.folder1 = strcat(app.MF,'00',num2str(NL));
                        end
                        % 3. choose which audio
                        NA = randi([1 10],1,1);
                        if NA < 10
                            app.audioN = strcat(app.folder1,'00',num2str(NA));
                        else
                            app.audioN = strcat(app.folder1,'0',num2str(NA));
                        end
                        
                        app.FolderFullPath = strcat('.\Data\Alcaim1_\',app.folder1,'\');
                        app.fileName = app.audioN;                        
                        
                        if strcmp(app.audioN,app.previousAudio{k,1}) == 0
                            %disp('Exit::')
                            break
                            %app.flagAudioRepeated = 1;
                        end
                        
                    end
                end
                
                if (k == app.audioExec-1) && (app.flagAudioRepeated == 0)
                    app.flagAudioRepeated = 1;
                end
                %%%% ----------------------------           
            end
            %%%% !!!!!!!!!!!!!!!!!!!!!!!!!!!
            
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            
            % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;     
            
            % Number of words per sentence
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            for iWord=1:length(app.sentenceWords)
                app.wordsAll{app.audioExec,iWord} = app.sentenceWords(iWord);
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end
               
            % Process audio
            app.signal_out = sigproc(char(app.FolderFullPath), char(app.fileName), ...
                app.noisetypeButtonGroup.SelectedObject.Text, app.snr_value, ...
                app.GainButtonGroup.SelectedObject.Text, app.earref, app.AZN, 0);
            
            % Call vocoder function
            [app.signal_out, ~] = CI_Vocoder_Cochlear(app.signal_out(:,app.channelOutput),...
                16000, 'CI');            
            
            % update status
            app.StatusdereproduoLamp.Color = 'green';
                    
            % reproduce audio signal (output has to be based on CI channel?)
            %             if app.Stereo.Value == 1
            %                 player = audioplayer(app.signal_out,16000);
            %             elseif app.Mono.Value == 1
            %                 player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            %             end
            player = audioplayer(app.signal_out,16000);
            player.play
            pause(length(app.signal_out)/8000) % intervalo para reprodução
                
            app.StatusdereproduoLamp.Color = 'red';
            app.ComputeButton.Enable = 'on';              
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.StartButton = uibutton(app.ControlPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.GainButtonGroup = uibuttongroup(app.UIFigure);
            app.GainButtonGroup.Title = 'Algorithm';
            app.MMSEButton = uiradiobutton(app.GainButtonGroup);
            app.MMSEButton.Text = 'MMSE';
            app.MMSEButton.Value = true;
            app.WienerButton = uiradiobutton(app.GainButtonGroup);
            app.WienerButton.Text = 'Wiener';
            app.BinaryMaskButton = uiradiobutton(app.GainButtonGroup);
            app.BinaryMaskButton.Text = 'Binary Mask';
            app.UnprocessedButton = uiradiobutton(app.GainButtonGroup);
            app.UnprocessedButton.Text = 'Unprocessed';
            app.noisetypeButtonGroup = uibuttongroup(app.UIFigure);
            app.noisetypeButtonGroup.Title = 'Noise';
            app.babble = uiradiobutton(app.noisetypeButtonGroup);
            app.babble.Text = 'babble';
            app.babble.Value = true;
            app.icra = uiradiobutton(app.noisetypeButtonGroup);
            app.icra.Text = 'ICRA';
            app.SSN_IEEE = uiradiobutton(app.noisetypeButtonGroup);
            app.SSN_IEEE.Text = 'SSN_IEEE';
            app.noiseazimuthangles = uibuttongroup(app.UIFigure);
            app.noiseazimuthangles.Title = 'noise azimuth ';
            app.plusninety = uiradiobutton(app.noiseazimuthangles);
            app.plusninety.Text = '90˚';
            app.plusninety.Value = true;
            app.zero = uiradiobutton(app.noiseazimuthangles);
            app.zero.Text = '0˚';
            app.minusninety = uiradiobutton(app.noiseazimuthangles);
            app.minusninety.Text = '-90˚';
            app.sideofCIButtonGroup = uibuttongroup(app.UIFigure);
            app.sideofCIButtonGroup.Title = 'side of CI';
            app.leftButton = uiradiobutton(app.sideofCIButtonGroup);
            app.leftButton.Text = 'left';
            app.leftButton.Value = true;
            app.rightButton = uiradiobutton(app.sideofCIButtonGroup);
            app.rightButton.Text = 'right';
            app.bilateralButton = uiradiobutton(app.sideofCIButtonGroup);
            app.bilateralButton.Text = 'bilateral';
            app.DatarepresentationPanel = uipanel(app.UIFigure);
            app.DatarepresentationPanel.Title = 'Data representation';
            app.udioemexecuoTextAreaLabel = uilabel(app.DatarepresentationPanel);
            app.udioemexecuoTextAreaLabel.Text = 'Sentence ';
            app.fraseExecucao = uitextarea(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel = uilabel(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel.Text = 'Status:';
            app.StatusdereproduoLamp = uilamp(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel = uilabel(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel.Text = {'Green  - Running'; 'Red -  Listening'; 'Blue - in progress'};
            app.p1Button_1 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_1.Text = 'p1';
            app.p1Button_2 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_2.Text = 'p1';
            app.p1Button_3 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_3.Text = 'p1';
            app.p1Button_4 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_4.Text = 'p1';
            app.p1Button_5 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_5.Text = 'p1';
            app.p1Button_6 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_6.Text = 'p1';
            app.p1Button_7 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_7.Text = 'p1';
            app.p1Button_8 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_8.Text = 'p1';
            app.p1Button_9 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_9.Text = 'p1';
            app.Panel = uipanel(app.DatarepresentationPanel);
            app.TotalwordscorrectLabel = uilabel(app.Panel);
            app.TotalwordscorrectLabel.Text = 'Total words correct';
            app.palavrasCorretas = uieditfield(app.Panel, 'numeric');
            app.palavrasCorretas.Editable = 'off';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.Panel);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Total words';
            app.totalPalavras = uieditfield(app.Panel, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.Next = uibutton(app.Panel, 'state');
            app.Next.ValueChangedFcn = createCallbackFcn(app, @NextValueChanged, true);
            app.Next.Enable = 'off';
            app.Next.Text = 'Next';
            app.ComputeButton = uibutton(app.Panel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.p1Button_10 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_10.Text = 'p1';
            app.p1Button_11 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_11.Text = 'p1';
            app.repeatLastAudio = uibutton(app.DatarepresentationPanel, 'state');
            app.repeatLastAudio.ValueChangedFcn = createCallbackFcn(app, @repeatLastAudioValueChanged, true);
            app.repeatLastAudio.Enable = 'off';
            app.repeatLastAudio.Text = 'Repeat';
            app.SNRdBButtonGroup = uibuttongroup(app.UIFigure);
            app.SNRdBButtonGroup.Title = 'SNR [dB]';
            app.minusfivedb = uiradiobutton(app.SNRdBButtonGroup);
            app.minusfivedb.Text = '-5';
            app.minusfivedb.Value = true;
            app.zerodb = uiradiobutton(app.SNRdBButtonGroup);
            app.zerodb.Text = '0';
            app.plusfivadb = uiradiobutton(app.SNRdBButtonGroup);
            app.plusfivadb.Text = '5';
            app.twofivedb = uiradiobutton(app.SNRdBButtonGroup);
            app.twofivedb.Text = '2.5';
            app.sevenfivedb = uiradiobutton(app.SNRdBButtonGroup);
            app.sevenfivedb.Text = '7.5';
            app.NumberAudio = uilabel(app.UIFigure);
            app.NumberAudio.Text = '-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_nomohe()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end