classdef interface_rafael < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        ScenarioconfigurationPanel matlab.ui.container.Panel % Scenario con...
        noisetypeButtonGroup    matlab.ui.container.ButtonGroup    % noise ...
        babble                  matlab.ui.control.RadioButton      % babble
        ICRA                    matlab.ui.control.RadioButton      % ICRA
        SSN_IEEE                matlab.ui.control.RadioButton      % SSN_IEEE
        noiseazimuthangles      matlab.ui.container.ButtonGroup    % noise ...
        plusninety              matlab.ui.control.RadioButton      % 90˚
        zero                    matlab.ui.control.RadioButton      % 0˚
        minusninety             matlab.ui.control.RadioButton      % -90˚
        sideofCIButtonGroup     matlab.ui.container.ButtonGroup    % side o...
        leftButton              matlab.ui.control.RadioButton      % left
        rightButton             matlab.ui.control.RadioButton      % right
        bilateralButton         matlab.ui.control.RadioButton      % bilate...
        GainButtonGroup         matlab.ui.container.ButtonGroup    % NR Tec...
        MMSEButton              matlab.ui.control.RadioButton      % MMSE
        WienerButton            matlab.ui.control.RadioButton      % Wiener
        BinaryMaskButton        matlab.ui.control.RadioButton      % Binary...
        UnprocessedButton       matlab.ui.control.RadioButton      % Unproc...
        AdaptativeintelligibilitytestprotocolPanel matlab.ui.container.Panel
        AdaptativetestprogressPanel matlab.ui.container.Panel % Adaptative ...
        SNRLabel                matlab.ui.control.Label            % SNR: 
        valueSNR                matlab.ui.control.Label            % ------
        AudiosentenceLabel      matlab.ui.control.Label            % Audio ...
        sentence                matlab.ui.control.Label            % ------
        CicletwodownoneupLabel  matlab.ui.control.Label            % Cicle ...
        numberCicle             matlab.ui.control.Label            % ------
        NumberAudio             matlab.ui.control.Label            % -
        DatarepresentationPanel matlab.ui.container.Panel          % Data r...
        udioemexecuoTextAreaLabel matlab.ui.control.Label % Sentence 
        fraseExecucao           matlab.ui.control.TextArea        
        StatusdereproduoLampLabel matlab.ui.control.Label % Status:
        StatusdereproduoLamp    matlab.ui.control.Lamp            
        VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel matlab.ui.control.Label
        p1Button_1              matlab.ui.control.StateButton      % p1
        p1Button_2              matlab.ui.control.StateButton      % p1
        p1Button_3              matlab.ui.control.StateButton      % p1
        p1Button_4              matlab.ui.control.StateButton      % p1
        p1Button_5              matlab.ui.control.StateButton      % p1
        p1Button_6              matlab.ui.control.StateButton      % p1
        p1Button_7              matlab.ui.control.StateButton      % p1
        p1Button_8              matlab.ui.control.StateButton      % p1
        p1Button_9              matlab.ui.control.StateButton      % p1
        Panel                   matlab.ui.container.Panel         
        TotalwordscorrectLabel  matlab.ui.control.Label            % Total ...
        palavrasCorretas        matlab.ui.control.NumericEditField % [-Inf ...
        NmerototaldepalavrasEditFieldLabel matlab.ui.control.Label % Total ...
        totalPalavras           matlab.ui.control.NumericEditField % [-Inf ...
        Next                    matlab.ui.control.StateButton      % Next
        ComputeButton           matlab.ui.control.Button           % Compute
        p1Button_10             matlab.ui.control.StateButton      % p1
        p1Button_11             matlab.ui.control.StateButton      % p1
        repeatLastAudio         matlab.ui.control.StateButton      % Repeat
        ControlpanelPanel       matlab.ui.container.Panel          % Contro...
        StartButton             matlab.ui.control.StateButton      % Start
        StopButton              matlab.ui.control.StateButton      % Stop
        MonoStereo              matlab.ui.container.ButtonGroup    % Mono/S...
        Stereo                  matlab.ui.control.RadioButton      % Stereo
        Mono                    matlab.ui.control.RadioButton      % Mono
    end


    properties (Access = private)
        signal_out              % Description
        flagSNRStep             % Description
        flagCounterCicles       % Description
        snr_value               % Description 
        earref                  % Description 
        AZN                     % Description 
        correctRate             % Description 
        sentenceWords           % Description 
        FolderFullPath          % Description 
        fileName                % Description  
        correctWords            % Description  
        Results                 % Description  
        NumTotalWords           % Description 
        audioExec               % Description 
        wordsAll                % Description 
        snr_vecValues           % Description 
        MF                      % Description 
        folder1                 % Description 
        audioN                  % Description 
        previousAudio           % Description 
        flagTwodown             % Description 
        flagChooseOtherGain     % Description
        channelOutput           % Description
        flagVSameAudio          % Description
        flagAudioRepeated       % Description
    end

    methods (Access = private)
        
        function clearWordsButtons(app)
            % Clear sentence words buttons
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
                eval(sprintf('app.p1Button_%.0f.Value = 0;',iWord))
            end 
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.UIFigure.Resize = 'off';
            for iWord=1:11
                eval(sprintf('app.p1Button_%.0f.Visible = ''off'';',iWord))
            end
            app.correctWords = cell(200,11);
            app.wordsAll = cell(200,11);
            app.previousAudio = cell(200,1); % Used to control which audio has been show
            app.flagChooseOtherGain = 0; % control flag of first algorithm choose
        end

        % Value changed function
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
                                  
            % disable scenario configuration
            BG = allchild(app.ScenarioconfigurationPanel); % all button groups in this panel
            for ii = 1: length(BG)
                Bttns = allchild(BG(ii)); % finds all buttons of the group
                for jj = 1:length(Bttns)
                    Bttns(jj).Enable = 'off';
                end
            end
            
            % enable Repeat audio button
            app.repeatLastAudio.Enable = 'on';
            
            % clear word buttons
            app.clearWordsButtons();
            
            % Flags two down-one up
            app.flagTwodown = 1;
            
            % Create vector of results (considering that, during each list, will be presented 200 sentences)
            app.Results = zeros(200,1);
            app.NumTotalWords = zeros(200,1);
            app.snr_vecValues = zeros(200,1);
            
            if app.flagChooseOtherGain == 0
                % This index has to be related to randomly list            
                app.audioExec = 1;
            end
            
            % Disable button
            app.StartButton.Enable = 'off';
            
            % Counter of cicles (6)
            app.flagCounterCicles = 0;
            
            % Control SNR step (0 - 4dB / 1 - 2dB). Update based on app.correctRate variable
            app.flagSNRStep = 0; 
            
            % Initial SNR presented is 16dB
            app.valueSNR.Text = '16dB'; 
            app.snr_value = 16;
            
            % Get CI side (1 - right / 4 - left)
            if app.leftButton.Value == 1
                app.earref = 1;
                app.channelOutput = 1; % Output channel (related to IC side)
            elseif app.rightButton.Value == 1
                app.earref = 4;
                app.channelOutput = 2; % Output channel (related to IC side)
            else
                app.earref = 1; %(Bilateral not considered, so left is the reference)
            end
            
            % Get noise signal azimuth
            if app.plusninety.Value == 1
                app.AZN = 90;
            elseif app.zero.Value == 1
                app.AZN = 0;
            else
                app.AZN = -90;
            end
            
            % Get audio from Data\Alcaim1_ aleatory ----------------------------
            % compose name aleatory
            % 1. choose between M and F
            MFtemp = randi([0 1],1,1);
            if MFtemp == 0
                app.MF = 'F';
            else
                app.MF = 'M';
            end
            % 2. choose which list
            NL = randi([1 10],1,1);
            if NL < 10
                app.folder1 = strcat(app.MF,'000',num2str(NL));
            else
                app.folder1 = strcat(app.MF,'00',num2str(NL));
            end
            % 3. choose which audio
            NA = randi([1 10],1,1);
            if NA < 10
                app.audioN = strcat(app.folder1,'00',num2str(NA));
            else
                app.audioN = strcat(app.folder1,'0',num2str(NA));
            end
            
            app.FolderFullPath = strcat('Data',filesep,...
                'Alcaim1_',filesep,app.folder1,filesep);
            app.fileName = app.audioN;
            %             app.FolderFullPath = strcat('Data\Alcaim1_\M0001\');
            %             app.fileName = 'M0001009';
            
            app.previousAudio{app.audioExec,1} = app.audioN;
            %%%% ----------------------------
            
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.WAV'));
            
            % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;
            
            % Numero de palavras na frase
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                 eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end
               
            % Call function sigproc - Process audio
            app.signal_out = sigproc(char(app.FolderFullPath), char(app.fileName), ...
                app.noisetypeButtonGroup.SelectedObject.Text, app.snr_value, ...
                app.GainButtonGroup.SelectedObject.Text, app.earref, app.AZN, 0);                
                
            % update status
            app.StatusdereproduoLamp.Color = 'green';
                    
            % reproduce audio signal 
            % About the test: record mono sterio audio and two source audio.
            if app.Stereo.Value == 1
                player = audioplayer(app.signal_out,16000);
            elseif app.Mono.Value == 1
                player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            end
            player.play
            %pause(5)
            pause(length(app.signal_out)/8000) % intervalo para reprodução
                
            app.StatusdereproduoLamp.Color = 'red';
            app.ComputeButton.Enable = 'on';
            
        end

        % Button pushed function
        function ComputeButtonPushed(app, event)
            
            % Indicate numbers of audios executed (max = 200)
            app.NumberAudio.Text = num2str(app.audioExec);
            
            cumCorr = 0;
            
            for iWord=1:length(app.sentenceWords)
                eval(sprintf('value = app.p1Button_%.0f.Value;',iWord))
                eval(sprintf('palavra = app.p1Button_%.0f.Text;',iWord))
                if value == 1
            
                    cumCorr = cumCorr + 1;
                    app.correctWords{app.audioExec,iWord} = palavra;
                    
                end
                
            end
            
            app.palavrasCorretas.Value = cumCorr;
            app.Results(app.audioExec) = app.palavrasCorretas.Value;
            app.NumTotalWords(app.audioExec) = app.totalPalavras.Value;            
            app.snr_vecValues(app.audioExec) = app.snr_value;
            
            app.Next.Enable = 'on';
            
            % Now compute rate of correct words, then update variables app.correctRate and app.snr_value                            
            app.correctRate = app.palavrasCorretas.Value/app.totalPalavras.Value;
            
            
            % higher than 50% of correct words
            if (app.correctRate >= .5) && (app.flagSNRStep == 0) && (app.flagTwodown < 2)
                
                app.flagTwodown = app.flagTwodown + 1;
            
            elseif (app.correctRate >= .5) && (app.flagSNRStep == 0) && (app.flagTwodown >= 2)
            
                app.snr_value = app.snr_value - 4;
                app.flagTwodown = 1;
            
            elseif (app.correctRate < .5)
            
                app.snr_value = app.snr_value + 2;
                app.flagSNRStep = 1;
                
                % Counter of completed cicles (maximum = 6)
                app.flagCounterCicles = app.flagCounterCicles + 1;                
                
            elseif (app.correctRate >= .5) && (app.flagSNRStep == 1) && (app.flagTwodown < 2)
            
                app.flagTwodown = app.flagTwodown + 1;
                
            elseif (app.correctRate >= .5) && (app.flagSNRStep == 1) && (app.flagTwodown >= 2)
                
                app.snr_value = app.snr_value - 2;
                app.flagTwodown = 1;
                
            end
                
            %             % Increment list of execution audios
            %             app.audioExec = app.audioExec + 1; 
            
            % Counter of completed inversions (maximum = 10)
            if app.flagCounterCicles >= 10
                
                % disable buttons                              
                app.ComputeButton.Enable = 'off';
                app.Next.Enable = 'off';
                app.StartButton.Enable = 'on';
                
                % save all variables
                disp('Save results')
                
                resultados.numTotalPalavras = app.NumTotalWords;
                resultados.numAcertos = app.Results;
                resultados.palCorretas = app.correctWords;
                resultados.wordsAll = app.wordsAll;
                resultados.snr_vecValues = app.snr_vecValues;
                
                [savefilename,savepathname] = uiputfile('*.mat');
                save(fullfile(savepathname,savefilename),'resultados') 
                
                app.flagChooseOtherGain = 1;
                msgbox('Choose another NR technique')
                
                % enable scenario configuration
                BG = allchild(app.ScenarioconfigurationPanel); % all button groups in this panel
                for ii = 1: length(BG)
                    Bttns = allchild(BG(ii)); % finds all buttons of the group
                    for jj = 1:length(Bttns)
                        Bttns(jj).Enable = 'on';
                    end
                end
                %app.Next.Enable = 'off';
                %app.StartButton.Enable = 'on';
                
                %%% Create a clever way to close/stop program..
                % Stop this list!
                %app.delete
                
            end            
            
        end

        % Value changed function
        function NextValueChanged(app, event)
            value = app.Next.Value;
            
            
            % Increment list of execution audios
            app.audioExec = app.audioExec + 1;             
                     
            % Disable buttons
            app.ComputeButton.Enable = 'off';
            app.Next.Enable = 'off';
            
            % Update SNR presented is 30dB
            app.valueSNR.Text = num2str(app.snr_value);
            app.numberCicle.Text = num2str(app.flagCounterCicles);
            app.sentence.Text = app.fileName;
            
            % Update sentence words presentation
            app.clearWordsButtons();           
            
            %%%% !!!!!!!!!!!!!!!!!!!!!!!!!!!
            %%% AFTER FINISH ALGORITHM 1, THE NEXT SET OF AUDIOS HAS TO BE DIFFERENTS!
            app.flagVSameAudio = 0;
            app.flagAudioRepeated = 0;
            while app.flagAudioRepeated == 0
                % Get audio from .\Data\Alcaim1_ aleatory ----------------------------
                % compose name aleatory
                % 1. choose between M and F
                MFtemp = randi([0 1],1,1);
                if MFtemp == 0
                    app.MF = 'F';
                else
                    app.MF = 'M';
                end
                % 2. choose which list
                NL = randi([1 10],1,1);
                if NL < 10
                    app.folder1 = strcat(app.MF,'000',num2str(NL));
                else
                    app.folder1 = strcat(app.MF,'00',num2str(NL));
                end
                % 3. choose which audio
                NA = randi([1 10],1,1);
                if NA < 10
                    app.audioN = strcat(app.folder1,'00',num2str(NA));
                else
                    app.audioN = strcat(app.folder1,'0',num2str(NA));
                end
            
                app.FolderFullPath = strcat('.',filesep,'Data',filesep,...
                    'Alcaim1_',filesep,app.folder1,filesep);
                app.fileName = app.audioN;
                
                app.previousAudio{app.audioExec,1} = app.audioN;
            
                %disp('Audio chosed::')
                %disp(app.audioN)
                
                % Chech if audio has already been show (CHECK ON ALL CELL...)              
                for k=1:1:app.audioExec-1
                    if strcmp(app.audioN,app.previousAudio{k,1}) == 1
                        
                        
                        %disp('Already exist::')
                        %disp(app.audioN)
                        
                        MFtemp = randi([0 1],1,1);
                        if MFtemp == 0
                            app.MF = 'F';
                        else
                            app.MF = 'M';
                        end
                        % 2. choose which list
                        NL = randi([1 10],1,1);
                        if NL < 10
                            app.folder1 = strcat(app.MF,'000',num2str(NL));
                        else
                            app.folder1 = strcat(app.MF,'00',num2str(NL));
                        end
                        % 3. choose which audio
                        NA = randi([1 10],1,1);
                        if NA < 10
                            app.audioN = strcat(app.folder1,'00',num2str(NA));
                        else
                            app.audioN = strcat(app.folder1,'0',num2str(NA));
                        end
            
                        app.FolderFullPath = strcat('.',filesep,'Data',filesep,...
                            'Alcaim1_',filesep,app.folder1,filesep);
                        app.fileName = app.audioN;                        
                        
                        %disp('New audio choosed:')
                        %disp(app.audioN)
                        
                        if strcmp(app.audioN,app.previousAudio{k,1}) == 0
                            %disp('Exit::')
                            break
                            %app.flagAudioRepeated = 1;
                        end    
                    end
                end
                
                if (k == app.audioExec-1) && (app.flagAudioRepeated == 0)
                    app.flagAudioRepeated = 1;
                end                
                %%%% ----------------------------            
            end
            %%%% !!!!!!!!!!!!!!!!!!!!!!!!!!!
            
            % Load metadata from audio
            fullFileName = fullfile(app.FolderFullPath,strcat(app.fileName,'.wav'));
            if ~exist(fullFileName, 'file')
                fullFileName(end-2:end) = 'WAV';   %if the .wav file does not exist, change extension to .WAV 
            end
            
            % Le audio e metadata
            audioInformations = audioinfo(fullFileName);
            app.fraseExecucao.Value = audioInformations.Title;     
            
            % Number of words per sentence
            app.sentenceWords = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.sentenceWords);
            for iWord=1:length(app.sentenceWords)
                app.wordsAll{app.audioExec,iWord} = app.sentenceWords(iWord);
                eval(sprintf('app.p1Button_%.0f.Text=app.sentenceWords{%.0f};',iWord,iWord))
                eval(sprintf('app.p1Button_%.0f.Visible = ''on'';',iWord))
            end
               
            % Process audio
            app.signal_out = sigproc(char(app.FolderFullPath), char(app.fileName), ...
                app.noisetypeButtonGroup.SelectedObject.Text, app.snr_value, ...
                app.GainButtonGroup.SelectedObject.Text, app.earref, app.AZN, 0);
                
            % update status
            app.StatusdereproduoLamp.Color = 'green';
                    
            % reproduce audio signal (output has to be based on CI channel?)
            if app.Stereo.Value == 1
                player = audioplayer(app.signal_out,16000);
            elseif app.Mono.Value == 1
                player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            end
            player.play
            pause(length(app.signal_out)/8000) % intervalo para reprodução
                
            app.StatusdereproduoLamp.Color = 'red';
            app.ComputeButton.Enable = 'on';  
            
            
        end

        % Value changed function
        function repeatLastAudioValueChanged(app, event)
            value = app.repeatLastAudio.Value;
            
            % update status
            app.StatusdereproduoLamp.Color = 'green';            
            
            % reproduce audio signal (output has to be based on CI channel?)
            if app.Stereo.Value == 1
                player = audioplayer(app.signal_out,16000);
            elseif app.Mono.Value == 1
                player = audioplayer(app.signal_out(:,app.channelOutput),16000);
            end
            player.play
            %pause(10)
            pause(length(app.signal_out)/8000) % intervalo para reprodução            
           
            app.StatusdereproduoLamp.Color = 'red';
            app.ComputeButton.Enable = 'on';             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ScenarioconfigurationPanel = uipanel(app.UIFigure);
            app.ScenarioconfigurationPanel.Title = 'Scenario configuration';
            app.ScenarioconfigurationPanel.Units = 'pixels';
            app.noisetypeButtonGroup = uibuttongroup(app.ScenarioconfigurationPanel);
            app.noisetypeButtonGroup.Title = 'noise type';
            app.noisetypeButtonGroup.Units = 'pixels';
            app.babble = uiradiobutton(app.noisetypeButtonGroup);
            app.babble.Text = 'babble';
            app.babble.Value = true;
            app.ICRA = uiradiobutton(app.noisetypeButtonGroup);
            app.ICRA.Text = 'ICRA';
            app.SSN_IEEE = uiradiobutton(app.noisetypeButtonGroup);
            app.SSN_IEEE.Text = 'SSN_IEEE';
            app.noiseazimuthangles = uibuttongroup(app.ScenarioconfigurationPanel);
            app.noiseazimuthangles.Title = 'noise azimuth angle(speech in front)';
            app.noiseazimuthangles.Units = 'pixels';
            app.plusninety = uiradiobutton(app.noiseazimuthangles);
            app.plusninety.Text = '90˚';
            app.plusninety.Value = true;
            app.zero = uiradiobutton(app.noiseazimuthangles);
            app.zero.Text = '0˚';
            app.minusninety = uiradiobutton(app.noiseazimuthangles);
            app.minusninety.Text = '-90˚';
            app.sideofCIButtonGroup = uibuttongroup(app.ScenarioconfigurationPanel);
            app.sideofCIButtonGroup.Title = 'side of CI';
            app.sideofCIButtonGroup.Units = 'pixels';
            app.leftButton = uiradiobutton(app.sideofCIButtonGroup);
            app.leftButton.Text = 'left';
            app.leftButton.Value = true;
            app.rightButton = uiradiobutton(app.sideofCIButtonGroup);
            app.rightButton.Text = 'right';
            app.bilateralButton = uiradiobutton(app.sideofCIButtonGroup);
            app.bilateralButton.Enable = 'off';
            app.bilateralButton.Text = 'bilateral (N/A)';
            app.GainButtonGroup = uibuttongroup(app.ScenarioconfigurationPanel);
            app.GainButtonGroup.Title = 'NR Technique';
            app.GainButtonGroup.Units = 'pixels';
            app.MMSEButton = uiradiobutton(app.GainButtonGroup);
            app.MMSEButton.Text = 'MMSE';
            app.WienerButton = uiradiobutton(app.GainButtonGroup);
            app.WienerButton.Text = 'Wiener';
            app.BinaryMaskButton = uiradiobutton(app.GainButtonGroup);
            app.BinaryMaskButton.Text = 'Binary Mask';
            app.UnprocessedButton = uiradiobutton(app.GainButtonGroup);
            app.UnprocessedButton.Text = 'Unprocessed';
            app.UnprocessedButton.Value = true;
            app.AdaptativeintelligibilitytestprotocolPanel = uipanel(app.UIFigure);
            app.AdaptativeintelligibilitytestprotocolPanel.Title = 'Adaptative intelligibility test protocol';
            app.AdaptativeintelligibilitytestprotocolPanel.Units = 'pixels';
            app.AdaptativetestprogressPanel = uipanel(app.UIFigure);
            app.AdaptativetestprogressPanel.Title = 'Adaptative test progress';
            app.AdaptativetestprogressPanel.Units = 'pixels';
            app.SNRLabel = uilabel(app.AdaptativetestprogressPanel);
            app.SNRLabel.Text = 'SNR: ';
            app.valueSNR = uilabel(app.AdaptativetestprogressPanel);
            app.valueSNR.Text = '------';
            app.AudiosentenceLabel = uilabel(app.AdaptativetestprogressPanel);
            app.AudiosentenceLabel.Text = 'Audio sentence: ';
            app.sentence = uilabel(app.AdaptativetestprogressPanel);
            app.sentence.Text = '------';
            app.CicletwodownoneupLabel = uilabel(app.AdaptativetestprogressPanel);
            app.CicletwodownoneupLabel.Text = 'Cicle (two down-one up):';
            app.numberCicle = uilabel(app.AdaptativetestprogressPanel);
            app.numberCicle.Text = '------';
            app.NumberAudio = uilabel(app.AdaptativetestprogressPanel);
            app.NumberAudio.Text = '-';
            app.DatarepresentationPanel = uipanel(app.UIFigure);
            app.DatarepresentationPanel.Title = 'Data representation';
            app.DatarepresentationPanel.Units = 'pixels';
            app.udioemexecuoTextAreaLabel = uilabel(app.DatarepresentationPanel);
            app.udioemexecuoTextAreaLabel.Text = 'Sentence ';
            app.fraseExecucao = uitextarea(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel = uilabel(app.DatarepresentationPanel);
            app.StatusdereproduoLampLabel.Text = 'Status:';
            app.StatusdereproduoLamp = uilamp(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel = uilabel(app.DatarepresentationPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoAzulLabel.Text = {'Green  - Running'; 'Red -  Listening'; 'Blue - in progress'};
            app.p1Button_1 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_1.Text = 'p1';
            app.p1Button_2 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_2.Text = 'p1';
            app.p1Button_3 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_3.Text = 'p1';
            app.p1Button_4 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_4.Text = 'p1';
            app.p1Button_5 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_5.Text = 'p1';
            app.p1Button_6 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_6.Text = 'p1';
            app.p1Button_7 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_7.Text = 'p1';
            app.p1Button_8 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_8.Text = 'p1';
            app.p1Button_9 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_9.Text = 'p1';
            app.Panel = uipanel(app.DatarepresentationPanel);
            app.Panel.Units = 'pixels';
            app.TotalwordscorrectLabel = uilabel(app.Panel);
            app.TotalwordscorrectLabel.Text = 'Total words correct';
            app.palavrasCorretas = uieditfield(app.Panel, 'numeric');
            app.palavrasCorretas.Editable = 'off';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.Panel);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Total words';
            app.totalPalavras = uieditfield(app.Panel, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.Next = uibutton(app.Panel, 'state');
            app.Next.ValueChangedFcn = createCallbackFcn(app, @NextValueChanged);
            app.Next.Enable = 'off';
            app.Next.Text = 'Next';
            app.ComputeButton = uibutton(app.Panel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.p1Button_10 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_10.Text = 'p1';
            app.p1Button_11 = uibutton(app.DatarepresentationPanel, 'state');
            app.p1Button_11.Text = 'p1';
            app.repeatLastAudio = uibutton(app.DatarepresentationPanel, 'state');
            app.repeatLastAudio.ValueChangedFcn = createCallbackFcn(app, @repeatLastAudioValueChanged);
            app.repeatLastAudio.Enable = 'off';
            app.repeatLastAudio.Text = 'Repeat';
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.ControlpanelPanel.Units = 'pixels';
            app.StartButton = uibutton(app.ControlpanelPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlpanelPanel, 'state');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.MonoStereo = uibuttongroup(app.ControlpanelPanel);
            app.MonoStereo.Title = 'Mono/Stereo Selection (N/A)';
            app.MonoStereo.Units = 'pixels';
            app.Stereo = uiradiobutton(app.MonoStereo);
            app.Stereo.Enable = 'off';
            app.Stereo.Text = 'Stereo';
            app.Mono = uiradiobutton(app.MonoStereo);
            app.Mono.Enable = 'off';
            app.Mono.Text = 'Mono';
            app.Mono.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_rafael()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end